classdef UI_master_RCS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RCS_System_Controller         matlab.ui.Figure
        VNAConfigurationButton        matlab.ui.control.Button
        GridLayout                    matlab.ui.container.GridLayout
        Post_processing               matlab.ui.control.Button
        Exportar_datos                matlab.ui.control.Button
        LaunchMultipleButton          matlab.ui.control.Button
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        ResetAppButton                matlab.ui.control.Button
        LoadStateButton               matlab.ui.control.Button
        CloseAppButton                matlab.ui.control.Button
        LoadConfigurationButton       matlab.ui.control.Button
        SaveConfigurationButton       matlab.ui.control.Button
        STARTMEASUREMENTButton        matlab.ui.control.Button
        PrepareSingleButton           matlab.ui.control.Button
        ResetConfigurationButton      matlab.ui.control.Button
        EMERGENCYSTOPButton           matlab.ui.control.Button
        VNAConfigurationPanel         matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        SweeptypePanel                matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        Num_of_averages_input         matlab.ui.control.NumericEditField
        NumofaveragesLabel            matlab.ui.control.Label
        Average_Sweep                 matlab.ui.control.CheckBox
        Singular_Sweep                matlab.ui.control.CheckBox
        SParameterstomeasurePanel     matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        Delete_S_parameter            matlab.ui.control.Button
        Add_S_parameter               matlab.ui.control.Button
        Input_S_parameter             matlab.ui.control.EditField
        DropDown_S_Parameters         matlab.ui.control.DropDown
        General_Parameters_VNA        matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        LowerFreqGHzEditField         matlab.ui.control.NumericEditField
        LowerFreqGHzEditFieldLabel    matlab.ui.control.Label
        UpperFreqGHzEditField         matlab.ui.control.NumericEditField
        UpperFreqGHzEditFieldLabel    matlab.ui.control.Label
        PowerdBmEditField             matlab.ui.control.NumericEditField
        PowerdBmEditFieldLabel        matlab.ui.control.Label
        IFBWHzEditField               matlab.ui.control.NumericEditField
        IFBWHzEditFieldLabel          matlab.ui.control.Label
        NumberofpointsEditField       matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel  matlab.ui.control.Label
        VNAModelDropDown              matlab.ui.control.DropDown
        VNAModelDropDownLabel         matlab.ui.control.Label
        ApplyConfigurationButton      matlab.ui.control.Button
        STATUSEditField               matlab.ui.control.EditField
        CheckConnectivityButton       matlab.ui.control.Button
        MobileSystemsPanel            matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        MakesureyouareconnectedtothePLCbeforeloadingmeasurementsLabel  matlab.ui.control.Label
        RCSMeasurementsCheckBox       matlab.ui.control.CheckBox
        configurationRCS              matlab.ui.control.Button
        configurationAntenna_Measurements  matlab.ui.control.Button
        AntennaMeasurementsCheckBox   matlab.ui.control.CheckBox
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
    end


    properties (Access = public)               

        % Rotating Desk System: 
        rot_desk_active = false;        
        rot_desk_save = true; % Para asegurar que se ha guarado una configuración exitosa con buena conexión con el Arduino         
        rot_desk
 
        % RCS System:
        rcs_system_active = false;
        rcs_system_save = true;
        rcs_system

        % S-Parameters selected:
        S_parameters
        % S_parameters = ["12","13","14","23","24","34", "21"]; %  "11"    "12"    "21"    "22" % CAMBIAR

        % Valid S-Parameters:
        valid_S_Parameters = {'S11','S12','S13','S14', ...
        'S21','S22','S23','S24', ...
        'S31','S32','S33','S34', ...
        'S41','S42','S43','S44','S66' ...
        };


        % Sweep type:
        sweep_type = "singular";
        num_averages = 1; % num of averages if we are in the average mode

        % Definimos colores que vamos a usar:
        colorNaranjaClarito = [0.94,0.68,0.43];
        colorVerde = [0,1,0];
        colorNegro = [0,0,0];
        colorBlanco = [1,1,1];
        colorRojo = [1,0,0];
        colorBoton = [0.96,0.96,0.96];
        colorLamp = [0.65,0.65,0.65];

        % Para saber cuando estamos realizando una medidas múltiples y
        % cuando no:
        medidasMultiples = false;

        % Inicializamos una celda para guardar los datos de cada
        % archivo:
        fileData = {};    

        % Diferentes Apps secundarias para la configuración de cada sistema
        % de movimiento:
        ConfigurationRotDesk;
        Configuration_RCS_system;
        exportDataUI;
        postProcessingUI;

        % VNA Configuration App:
        VNAConfigurationMeasure;    

        % Save S-parameters struct and the range of frequencies:
        H;
        Fv;
    end

    properties (Access = private)
        vna
        Pb
        F0 % Lower Frequency
        FF % Upper Frequency
        N % Number of Points
        IFBW
        
        % Para indicar si ha habido crasheo en una medida:
        recover_crash
        % Para indicar qué fichero ha crasheado (si hay múltiples):
        index_crash = 1;
        
        rd_points
        rot_desk_crash

        rcs_points
        rcs_system_crash

        H_crash
        count_crash
        t_crash

        % When we push Emergency Stop Button it will be "true":
        stopExecution = false;
    end
    
    % Para funciones dentro de la clase:
    methods (Access = public)
        function loadDataFile(app, fileIndex)
            % INPUTS:
            %   --> app: Global App
            %   --> fileIndex: Index which indicates the file that we want to
            %   measure
    
            % Cargamos los parámetros del archivo correspondiente en funcón
            % de su índice:
            if ~app.fileData{fileIndex}.crash_file % Si el fichero no ha tenido crash
    
                % We load the values of the 'VNA Configuration' panel:
                app.PowerdBmEditField.Value = app.fileData{fileIndex}.Pb;
                app.UpperFreqGHzEditField.Value = app.fileData{fileIndex}.FF;
                app.LowerFreqGHzEditField.Value = app.fileData{fileIndex}.F0;
                app.NumberofpointsEditField.Value = app.fileData{fileIndex}.N;
                app.IFBWHzEditField.Value = app.fileData{fileIndex}.IFBW;
                app.Num_of_averages_input.Value = app.fileData{fileIndex}.num_averages;
                
                % Variables needed to the next steps:
                app.S_parameters = app.fileData{fileIndex}.S_parameters;
                app.sweep_type = app.fileData{fileIndex}.sweep_type;

                % S-Parameters:
                app.DropDown_S_Parameters.Items = cellstr("S" + app.S_parameters);

                % Sweep type:
                if strcmp(app.sweep_type,'singular')
                    app.Singular_Sweep.Value = 1;   
                    app.Average_Sweep.Value = 0;
                    app.Num_of_averages_input.Enable = "off";
                elseif strcmp(app.sweep_type, 'average')
                    app.Singular_Sweep.Value = 0;   
                    app.Average_Sweep.Value = 1;        
                    app.Num_of_averages_input.Enable = "on";
                end

                % We check which systems we had active and load
                % the configuration of those systems. In the event that
                % a system did not have a default configuration
                % we initialize it to '1' with 'DummyClass()':

                % Antenna Measurements:
                if app.fileData{fileIndex}.rot_desk_active
                    app.rot_desk_active = true;
                    app.rot_desk = app.fileData{fileIndex}.rot_desk;
                    app.AntennaMeasurementsCheckBox.Value = true;
                    % Try close connections with the PLC if there are active
                    % connections:
                    try app.rot_desk.closeConnection(); catch, end;
                else
                    app.rot_desk_active = false;
                    app.rot_desk = DummyClass();
                    app.AntennaMeasurementsCheckBox.Value = false;
                end
                AntennaMeasurementsCheckBoxValueChanged(app);
                
                % RCS Measurments:
                if app.fileData{fileIndex}.rcs_system_active
                    app.rcs_system_active = true;
                    app.rcs_system = app.fileData{fileIndex}.rcs_system;
                    app.RCSMeasurementsCheckBox.Value = true;
                    % Try close connections with the PLC if there are active
                    % connections:
                    try app.rcs_system.closeConnection(); catch, end;
                else
                    app.rcs_system_active = false;
                    app.rcs_system = DummyClass();
                    app.RCSMeasurementsCheckBox.Value = false;
                end
                RCSMeasurementsCheckBoxValueChanged(app);                

                               
                % Ponemos el color por defecto de los botones:
                app.configurationAntenna_Measurements.BackgroundColor = app.colorBoton;
                app.configurationRCS.BackgroundColor = app.colorBoton;        
                
                % Ponemos el valor del crash a 'false':
                app.recover_crash = false;
    
                % Activamos estos botones:
                % app.LoadStateButton.Enable = "on";
                % app.SaveConfigurationButton.Enable = "on";
            end
        end

        % Function that ensures that at least one checkbox of the Sweep
        % type is active:
        function validateMinimumChechkBoxSweepType(app, changedCheckBox)
            % Get current status of checkboxes
            states = [
                app.Singular_Sweep.Value;
                app.Average_Sweep.Value;
            ];
    
            % Count how many are active:
            active = sum(states);
    
            % If there are less than 1 and one is being deactivated, cancel
            % the action:
            if active < 1
                changedCheckBox.Value = true;
                uialert(app.RCS_System_Controller, ...
                    "You must keep at least 1 sweep type activated.", ...
                    "Minimum selection");
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Función que se ejecuta cuando se carga la App pero sin que
            % todavía el usuario haya interactuado con ella                     

            % Añadimos los archivos necesarios:
            addpath('class','UI_systems');
               
            % Inicializamos las variables de coordenadas para el posterior
            % movimiento con la clase fictica "DummyClass()":
            app.rot_desk = DummyClass(); 
            app.rcs_system = DummyClass();
            
            % Establecemos el valor de crasheos a "false"
            app.recover_crash = false;
        end

        % Value changed function: AntennaMeasurementsCheckBox
        function AntennaMeasurementsCheckBoxValueChanged(app, event)
            value = app.AntennaMeasurementsCheckBox.Value;

            % Uncheck the other checkbox so that they are not both active 
            % at the same time:
            if app.RCSMeasurementsCheckBox.Value                
                % Close the other configurations (if they exist):            
                try app.Configuration_RCS_system.closeFigure(); catch, end           

                % Disable the other configuration:
                app.RCSMeasurementsCheckBox.Value = false;
                app.configurationRCS.Enable = "off";                                       
            end
            
            if value
                app.configurationAntenna_Measurements.Enable = "on";
                % Lo ponemos a 'false' para que se tenga en cuenta en las
                % comprobaciones de 'CheckConnectivity' del VNA:
                app.rot_desk_save = false;  
            else
                app.configurationAntenna_Measurements.Enable = "off";                
                % Lo ponemos a 'true' para que no se tenga en cuenta en las
                % comprobaciones de 'CheckConnectivity' del VNA:
                app.rot_desk_save = true; 
                app.configurationAntenna_Measurements.BackgroundColor = app.colorBoton;
                app.rot_desk_active = false;
                app.rot_desk = DummyClass();
            end
        end

        % Value changed function: RCSMeasurementsCheckBox
        function RCSMeasurementsCheckBoxValueChanged(app, event)
            value = app.RCSMeasurementsCheckBox.Value;

            % Uncheck the other checkbox so that they are not both active 
            % at the same time:
            if app.AntennaMeasurementsCheckBox.Value          
                % Close the other configurations (if they exist):                
                try app.ConfigurationRotDesk.closeFigure(); catch, end                
                
                % Disable the other configuration:
                app.AntennaMeasurementsCheckBox.Value = 0;
                app.configurationAntenna_Measurements.Enable = "off";                    
            end

            if value
                app.configurationRCS.Enable = "on";
                % Lo ponemos a 'false' para que se tenga en cuenta en las
                % comprobaciones de 'CheckConnectivity' del VNA:
                app.rcs_system_save = false;
            else
                app.configurationRCS.Enable = "off";                
                % Lo ponemos a 'true' para que no se tenga en cuenta en las
                % comprobaciones de 'CheckConnectivity' del VNA:
                app.rcs_system_save = true;  % Poner para sistema del arco
                app.configurationRCS.BackgroundColor = app.colorBoton;
                app.rcs_system_active = false;
                app.rcs_system = DummyClass(); 
            end
        end

        % Value changed function: LowerFreqGHzEditField
        function LowerFreqGHzEditFieldValueChanged(app, event)
            value = app.LowerFreqGHzEditField.Value;
            % Si el valor de Lower Freq. es mayor o igual que el de
            % Upper Freq. mostramos mensaje de error y ponemos la
            % Lower Freq. por debajo de la Upper Freq. (-0.1 GHz)
            if value >= app.UpperFreqGHzEditField.Value
                app.LowerFreqGHzEditField.Value = app.UpperFreqGHzEditField.Value - 0.1;
                uialert(app.RCS_System_Controller,sprintf('The lower frequency cannot be higher \n than the upper frequency'),'Bad Frequency Range');
            end
        end

        % Value changed function: UpperFreqGHzEditField
        function UpperFreqGHzEditFieldValueChanged(app, event)
            value = app.UpperFreqGHzEditField.Value;
            % Si el valor de Upper Freq. es menor o igual que el de 
            % Upper Freq. mostramos mensaje de error y ponemos la 
            % Upper Freq. por encima de la Upper Freq. (+0.1 GHz)
            if value <= app.LowerFreqGHzEditField.Value
                app.UpperFreqGHzEditField.Value = app.LowerFreqGHzEditField.Value + 0.1;
                uialert(app.RCS_System_Controller,sprintf('The upper frequency cannot be lower \n than the lower frequency'),'Bad Frequency Range');
            end
        end

        % Button pushed function: VNAConfigurationButton
        function VNAConfigurationButtonPushed(app, event)
            % We configure the ports which we will use and the S-parameters
            % to measure:
            app.VNAConfigurationButton.Enable = "off";            
            
            % We pass the main app (master app) to the VNA Configuration 
            % app in order to calculate values in this app:
            app.VNAConfigurationMeasure = UI_VNA_Configuration_RCS(app);
        end

        % Button pushed function: Add_S_parameter
        function Add_S_parameterButtonPushed(app, event)
            % We check if the user has introduced a correct value:
            s_parameter_selected = app.Input_S_parameter.Value;
                        
            % Remove spaces and switch to uppercase for normalization:
            s_parameter_selected  = upper(strtrim(s_parameter_selected ));

            % Add the letter S at the beginning if it does not have it:
            if ~startsWith(s_parameter_selected, 'S')
                s_parameter_selected = ['S' s_parameter_selected];
            end
            
            % If the user input corresponds to a valid S-parameter: 
            if any(strcmp(s_parameter_selected, app.valid_S_Parameters))
                % Check if it exists
                if ~ismember(s_parameter_selected, app.DropDown_S_Parameters.Items)
                    % Add only if it doesn't exist
                    app.DropDown_S_Parameters.Items{end+1} = s_parameter_selected;
                end            
    
                % We select the new item:
                app.DropDown_S_Parameters.Value = s_parameter_selected;
            else
                uialert(app.RCS_System_Controller, [s_parameter_selected ' is not a valid S-parameter.'], 'Error');
            end           
        end

        % Button pushed function: Delete_S_parameter
        function Delete_S_parameterButtonPushed(app, event)
            % We obtain the value selected in hte dropdown:
            selected = app.DropDown_S_Parameters.Value;
            
            % Obtain all the items of the dropdown:
            items = app.DropDown_S_Parameters.Items;

            % Search the selected index:
            idx = find(strcmp(items,selected));

            % We delete the selected item if it exists:
            if ~isempty(idx)
                items(idx) = []; % Delete the item
                app.DropDown_S_Parameters.Items = items;

                % In the case we don't have items:
                if isempty(items)
                    app.DropDown_S_Parameters.Items = {'S21'};
                    app.DropDown_S_Parameters.Value = 'S21';
                else
                    % Selection the first item:
                    app.DropDown_S_Parameters.Value = items{1};
                end
            end
        end

        % Value changed function: Singular_Sweep
        function Singular_SweepValueChanged(app, event)
            % Validate if at least one checkbox is active:
            app.validateMinimumChechkBoxSweepType(app.Singular_Sweep);      

            % Deactivate the other checkBox:
            app.Average_Sweep.Value = 0;           

            % We deactivate the input in order to asign the number of
            % averages:
            app.Num_of_averages_input.Enable = "off";

            % We save this sweep type:
            app.sweep_type = "singular";
        end

        % Value changed function: Average_Sweep
        function Average_SweepValueChanged(app, event)
            % Validate if at least one checkbox is active:
            app.validateMinimumChechkBoxSweepType(app.Average_Sweep);  

            % Deactivate the other checkBox:
            app.Singular_Sweep.Value = 0;

            % We activate the input in order to asign the number of
            % averages:
            app.Num_of_averages_input.Enable = "on";

            % We save this sweep type:
            app.sweep_type = "average";
        end

        % Button pushed function: Exportar_datos
        function Exportar_datosButtonPushed(app, event)
            % % Open the folder to select one measure:
            % originalDir = pwd;
            % cd([originalDir,'\measurements']); pause(0.01);
            % uiopen();
            % cd(originalDir); % We back to the original folder in order not to lose the reference           
            % 
            % try             
            %     % We save the values to pass them to the new UI:
            %     app.H = H;
            %     app.Fv = Fv;
            %     % Open the UI to show the results:
            %     app.exportDataUI = UI_export_data(app);
            % catch    
            %     uialert(app.RCS_System_Controller,'No valid file selected!','Warning','Icon','warning');
            % end

            app.exportDataUI = UI_export_data(app);
        end

        % Button pushed function: Post_processing
        function Post_processingButtonPushed(app, event)
            % Open the UI to show the results:
            app.postProcessingUI = UI_post_processing(app);            
        end

        % Button pushed function: CheckConnectivityButton
        function CheckConnectivityButtonPushed(app, event)
            % We close the connection first in case we already have a open
            % connection: 
            try app.vna.closeConnection(); catch, end
            
            % Para comprobar la conectividad del VNA
            app.CheckConnectivityButton.Enable = "off"; pause(0.01);
            switch app.VNAModelDropDown.Value
                case "R&S ZNA67"                   
                    try app.vna = RS_VNA('ZNA67'); catch, end                                
                case "R&S ZVA24"
                    try app.vna = RS_VNA('ZVA24'); catch, end                                
                case "Virtual VNA"
                    app.vna = DummyClass(); % Para clase ficticia       
            end            

            try
                app.vna.openConnection();
                app.STATUSEditField.BackgroundColor = app.colorVerde;
                app.STATUSEditField.FontColor = app.colorNegro;
                app.STATUSEditField.Value = "OK";

                % Comprobamos si vamos a realizar medidas múltiples o no:
                if(app.medidasMultiples)
                    app.PrepareSingleButton.Enable = "off";                    
                    app.LaunchMultipleButton.Enable = "on";
                else
                    app.PrepareSingleButton.Enable = "on";
                    app.LaunchMultipleButton.Enable = "off";
                end                               
                
                % We active these buttons:
                app.VNAConfigurationButton.Enable = "on";
                app.ApplyConfigurationButton.Enable = "on";
            catch
                app.ApplyConfigurationButton.Enable = "off";
                app.PrepareSingleButton.Enable = "off";
                app.LaunchMultipleButton.Enable = "off";
                app.STATUSEditField.BackgroundColor = app.colorRojo;
                app.STATUSEditField.FontColor = app.colorBlanco;
                app.STATUSEditField.Value = "CONNECTION OUT";
            end
            app.CheckConnectivityButton.Enable = "on";
        end

        % Button pushed function: ApplyConfigurationButton
        function ApplyConfigurationButtonPushed(app, event)
            % Recoge los datos introducidos de potencia, Frecuencia alta y
            % baja, IFBW y número de puntos:
            app.Pb = app.PowerdBmEditField.Value;
            app.F0 = app.LowerFreqGHzEditField.Value;
            app.FF = app.UpperFreqGHzEditField.Value;
            app.N = app.NumberofpointsEditField.Value;
            app.IFBW = app.IFBWHzEditField.Value;      
            app.num_averages = app.Num_of_averages_input.Value;
            
            % Save all the parameters selected in the dropdown:
            app.S_parameters = string(app.DropDown_S_Parameters.Items);
            app.S_parameters = extractAfter(app.S_parameters, 1);  % quita el primer carácter
            
            % Inicializa el VNA con esta configuración:            
            app.vna.initialization(app.Pb,app.F0,app.FF,app.N,app.IFBW, app.S_parameters, app.sweep_type, app.num_averages);
        end

        % Button pushed function: configurationAntenna_Measurements
        function configurationAntenna_MeasurementsButtonPushed(app, event)
            % We disable the configuration button so that the user does not
            % press it more than once:
            app.configurationAntenna_Measurements.Enable = "off";
            app.AntennaMeasurementsCheckBox.Enable = 'off';            
            
            % We pass the main app (master app) to the rotating_desk app in
            % order to calculate values in this app:
            app.ConfigurationRotDesk = UI_rotating_desk_RCS(app);
        end

        % Button pushed function: configurationRCS
        function configurationRCSButtonPushed(app, event)
            % We disable the configuration button so that the user does not
            % press it more than once:
            app.configurationRCS.Enable = "off";
            app.RCSMeasurementsCheckBox.Enable = 'off';
            
            % We pass the main app (master app) to the rotating_desk app in
            % order to calculate values in this app:
            app.Configuration_RCS_system = UI_rcs_system_RCS(app);
        end

        % Close request function: RCS_System_Controller
        function RCS_System_ControllerCloseRequest(app, event)
            % Close the APP when you click on the close button (top right)
            delete(app);
        end

        % Button pushed function: SaveConfigurationButton
        function SaveConfigurationButtonPushed(app, event)
            
            % Save the values of the actual configuration:
            Pb = app.PowerdBmEditField.Value;
            F0 = app.LowerFreqGHzEditField.Value;
            FF = app.UpperFreqGHzEditField.Value;
            N = app.NumberofpointsEditField.Value;
            IFBW = app.IFBWHzEditField.Value;

            % S-Parameters:            
            app.S_parameters = string(app.DropDown_S_Parameters.Items);
            app.S_parameters = extractAfter(app.S_parameters, 1);  % delete the first letter
            S_parameters = app.S_parameters;

            % Sweep type:
            sweep_type = app.sweep_type;
            num_averages = app.Num_of_averages_input.Value;
            
            % Antenna Measurements:
            rot_desk_active = app.rot_desk_active;
            rot_desk = app.rot_desk;

            % RCS Measurments:
            rcs_system_active = app.rcs_system_active;
            rcs_system = app.rcs_system;
            
            % No ha habido crasheos:
            crash_file = false;

            % We close the connection with the PLC before we save the
            % meaure:
            try app.rot_desk.closeConnection(); catch, end
            try app.rcs_system.closeConnection(); catch, end
            
            % Guardamos las variables en el fichero de "presets" para tener
            % poder cargar luego esta configuración:
            output = {'Pb','F0','FF','N','IFBW','S_parameters', 'sweep_type', 'num_averages', 'crash_file' ...
                'rot_desk_active','rot_desk','rcs_system_active','rcs_system'};
            
            uisave(output,sprintf('presets\\preset_%dGHz_%dGHz_%d_%dHz.mat',F0,FF,N,IFBW));

            % Volvemos a abrir la conexion por si se sigue usando la app
            try app.rot_desk.openConnection(); catch, end
            try app.rcs_system.openConnection(); catch, end
        end

        % Button pushed function: LoadConfigurationButton
        function LoadConfigurationButtonPushed(app, event)
                                   
            % Inicializamos el valor del crash al cargar nuevas
            % configuraciones:            
            app.index_crash = 1;
            app.stopExecution = false; % Inicializamos a false

            % Obtenemos el directorio actual con 'pwd':
            originalDir = pwd;

            % Abirmos la carpeta 'presets' dentor del directorio actual:            
            cd([originalDir,'\presets']); pause(0.01);            

            % Para seleccionar varios ficheros ".mat" en caso de que se
            % quiera cargar más de una configuración:
            filenames = uigetfile('*.mat*',  'MAT-files (*.mat*)','MultiSelect','on');  
            
            % Con esto guardamos cada fichero en una celda y dentro de esa
            % celda tenemos la estructura con todos sus parámetros.            
            
            % Deshabilitamos temporalmente los dos botones de "Prepare
            % Single" y "Launch Multiple":
            app.PrepareSingleButton.Enable = "off";
            app.LaunchMultipleButton.Enable = "off";

            % Indicamos en la conectividad del VNA que se vuelva a pulsar
            % el botón de Check Connectivity:
            app.STATUSEditField.BackgroundColor = app.colorRojo;
            app.STATUSEditField.FontColor = app.colorBlanco;
            app.STATUSEditField.Value = "TRY TO CONNECT";
            
            % Inicializamos de nuevo el fileData:
            app.fileData = {};            
            
            try                                                                                                
                % Para el caso en el que solo hayamos leído un fichero
                % lo convertimos en una celda para su posterior manejo:                       
                if(ischar(filenames)) % Si hemos leido un solo fichero tendremos un tipo "char"
                    filenames = {filenames};  
                    % Indicamos que NO vamos a trabajar con medidas
                    % múltiples:
                    app.medidasMultiples = false;                    
                elseif(iscell(filenames)) % Si hemos seleccionado varios ficheros tendremos un tipo "cell"
                    % Indicamos que SÍ vamos a trabajar con medidas
                    % múltiples:
                    app.medidasMultiples = true;                    
                end                          

                % Recorremos cada uno de los ficheros seleccionados y guardamos
                % los parámetros de cada fichero en cada una de las posiciones
                % de la celda global:      
                for i = 1:length(filenames)                             
                    app.fileData{i} = load(filenames{i});                    
                end                  

                % Llamos a la función para cargar los datos en la interfaz:
                fileIndex = 1; % Para que cargue por defecto el primer fichero
                loadDataFile(app, fileIndex);

                % We switch it back to false to recheck the connectivity
                % with the PLC:
                
                % Antenna Measurements:
                if app.rot_desk_active
                    app.rot_desk_save = false;
                end

                if app.rcs_system_active
                    app.rcs_system_save = false;
                end             

            catch
                % Si los ficheros han tenido crash o no corresponden con un
                % fichero de MATLAb no los abre:
                uialert(app.RCS_System_Controller,'No valid file selected!','Warning','Icon','warning');
            end         

            % Volvemos al directorio raíz para que no haya problemas para
            % cargar futuros archivos (que no cambie el 'originalDir'):
            cd(originalDir);                           
        end

        % Button pushed function: LoadStateButton
        function LoadStateButtonPushed(app, event)
            app.recover_crash = true;
            app.stopExecution = false; % Inicializamos a false
            
            % Volvemos a inicializar a 1 el index_crash cuando cargamos un
            % nuevo crash:
            app.index_crash = 1;

            originalDir = pwd;
            cd([originalDir,'\crash_states']); pause(0.01);
            uiopen();
            cd(originalDir);

            try
                if crash_file
                    % Cargamos el resto de ficheros en caso de que
                    % tuvieramos medidas múltiples:
                    app.fileData = file_data;

                    % Comprobamos si tenemos medida múltiples o no:
                    if medidasMultiples
                        app.medidasMultiples = true;
                    else
                        app.medidasMultiples = false;
                    end

                    app.PowerdBmEditField.Value = Pb;
                    app.UpperFreqGHzEditField.Value = FF;
                    app.LowerFreqGHzEditField.Value = F0;
                    app.NumberofpointsEditField.Value = N;
                    app.IFBWHzEditField.Value = IFBW;
                    app.Num_of_averages_input.Value = num_averages;

                    % Variables needed to the next steps:
                    app.S_parameters = S_parameters;
                    app.sweep_type = sweep_type;

                    % S-Parameters:
                    parameters_name = ["11","12","21","22"];
                    for i = 1:length(app.S_parameters)
                        if app.S_parameters(i) ~= ""
                            parameterCheckBox = sprintf('S%sCheckBox', char(parameters_name(i)));
                            app.(parameterCheckBox).Value = 1;
                        end
                    end
    
                    % Sweep type:
                    if strcmp(app.sweep_type,'singular')
                        app.Singular_Sweep.Value = 1;   
                        app.Average_Sweep.Value = 0;
                        app.Num_of_averages_input.Enable = "off";
                    elseif strcmp(app.sweep_type, 'average')
                        app.Singular_Sweep.Value = 0;   
                        app.Average_Sweep.Value = 1;
                        app.Num_of_averages_input.Enable = "on";
                    end
                    
                    app.H_crash = H;  
                    app.count_crash = mes_count;                 
                    app.t_crash = t;
                    
                    % Guardamos el punto por el que nos hemos quedado del
                    % grid: 
                    app.rd_points = rd_points;
                    app.rcs_points = rcs_points;
    
                    if rot_desk_active
                        app.rot_desk_active = true;
                        app.rot_desk_crash = rot_desk;
                        try app.rot_desk_crash.closeConnection(); catch, end
                        app.rot_desk = rot_desk;
                        app.AntennaMeasurementsCheckBox.Value = true;
                    else
                        app.rot_desk_active = false;
                        app.rot_desk = DummyClass();
                        app.AntennaMeasurementsCheckBox.Value = false;
                    end
                    AntennaMeasurementsCheckBoxValueChanged(app);
                    
                    if rcs_system_active
                        app.rcs_system_active = true;
                        app.rcs_system_crash = rcs_system;
                        try app.rcs_system_crash.closeConnection(); catch, end
                        app.rcs_system = rcs_system;
                        app.RCSMeasurementsCheckBox.Value = true;
                    else
                        app.rcs_system_active = false;
                        app.rcs_system = DummyClass();
                        app.RCSMeasurementsCheckBox.Value = false;
                    end
                    RCSMeasurementsCheckBoxValueChanged(app);
    
                    app.configurationAntenna_Measurements.BackgroundColor = app.colorBoton;
                    app.configurationRCS.BackgroundColor = app.colorBoton;
                    
                    % Llamamos al botón de "CheckConnectivity()" del VNA para
                    % comprobar si se ha pasado de medida Singular a medidas
                    % múltiples y así actualizar los botones:
                    CheckConnectivityButtonPushed(app);

                    app.SaveConfigurationButton.Enable = "off";
                    uialert(app.RCS_System_Controller,'Remember to realign the mobile systems!','Crash State','Icon','info');                    
                end
            catch
                % Si no se ha seleccionado ningún archivo ponemos
                % "recover_crash" a false para que se pueda ejecutar la
                % medida que está actualmente guardada en la interfaz:
                app.recover_crash = false;           
                uialert(app.RCS_System_Controller,'No valid file selected!','Warning','Icon','warning');
            end
        end

        % Button pushed function: PrepareSingleButton
        function PrepareSingleButtonPushed(app, event)
            try
                % Comprobamos si se ha conectado correctamente el
                % PLC o no. En el caso de medidas múltiples solo se 
                % comprueba si se trataba de la primera medida:
                
                systems = {
                    "Antenna Measurements", app.rot_desk_save;
                    "RCS System", app.rcs_system_save;
                };

                for i = 1:size(systems, 1)
                    if(~systems{i,2} && app.index_crash == 1)
                       uialert(app.RCS_System_Controller,sprintf('Check the PLC connection for the %s', systems{i,1}),'Check connection with PLC');
                       return;
                    elseif (~systems{i,2} && app.index_crash > 1)
                        uialert(app.RCS_System_Controller,'<html><center>Measurement Failed!<br> Wait for Reset App</center></html>', ...
                        'Fatal Error','Icon','error', 'Interpreter','html');
                        error('Check the PLC connection of the %s', systems{i,1});
                    end
                end

                % No dejamos clickar en ningún botón excepto en el botón
                % "Emergency Stop" una vez hemos pulsado el botón de "Prepare
                % Measurement"
                app.PrepareSingleButton.Enable = "off";
                app.MobileSystemsPanel.Enable = "off";
                app.VNAConfigurationPanel.Enable = "off";
                app.SaveConfigurationButton.Enable = "off";
                app.LoadConfigurationButton.Enable = "off";
                app.LoadStateButton.Enable = "off";
                app.ResetAppButton.Enable = "off";
                app.EMERGENCYSTOPButton.Enable = "on";
             
                % Lee los datos de potencia y frecuencias que se han
                % introducido en el cuadro del VNA en caso de que no se haya
                % pulsado el botón de "Apply Configuration"
                ApplyConfigurationButtonPushed(app);                                            

                % Tomamos el punto en el que se encuentra la antena como punto
                % de referencia, es decir, como el origen (X=0,Y=0,Z=0) a
                % partir del cual se va a formar el grid por el que se va a
                % desplazar la antena:
                app.rot_desk.setOrigin();
                app.rcs_system.setOriginRCS_Measurement();
                            
                % Si no ha habido ningún crasheo que se ejecute esta parte:
                if ~app.recover_crash
                    % Hacemos que se mueva a la siguiente posición del grid:
                    app.rot_desk.nextArrayPosition();
                    app.rcs_system.nextArrayPosition();          
                else 
                    % Si ha habido crasheo cargamos la posición por la que nos
                    % hayamos quedado.
    
                    % Comprobamos que configuraciones tenemos guardadas y con
                    % el "changeCoordinate" nos movemos al punto específico en
                    % el que se hubiera quedado la medición antes del crasheo:
                    if app.rot_desk_active
                        app.rot_desk.changeCoordinate('Phi',app.rot_desk_crash.phi);
                        app.rot_desk.changeCoordinate('Beta',app.rot_desk_crash.beta);
                        app.rot_desk.changeCoordinate('Theta',app.rot_desk_crash.theta);
                        app.rot_desk.changeCoordinate('X',app.rot_desk_crash.x);
                        app.rot_desk.changeCoordinate('Y',app.rot_desk_crash.y);
                        
                        % Propiedades del sistema rot_desk:
                        propsRotDeskCrash = properties(app.rot_desk_crash);
    
                        % Excluimos las 3 últimas propiedades para que no
                        % sobreescriba la conexión con el PLC:
                        propsRotDeskCrash = propsRotDeskCrash(1:end-3);
    
                        % Sobreescribimos valores del crash:
                        for i = 1:length(propsRotDeskCrash)
                            app.rot_desk.(propsRotDeskCrash{i}) = app.rot_desk_crash.(propsRotDeskCrash{i});
                        end  
                    end
                    if app.rcs_system_active
                        app.rcs_system.changeCoordinate('Phi',app.rcs_system_crash.phi);
                        app.rcs_system.changeCoordinate('Alpha_A1',app.rcs_system_crash.alpha_A1);
                        app.rcs_system.changeCoordinate('Alpha_A2',app.rcs_system_crash.alpha_A2);
                        app.rcs_system.changeCoordinate('Beta_A1',app.rcs_system_crash.beta_A1);
                        app.rcs_system.changeCoordinate('Beta_A2',app.rcs_system_crash.beta_A2);
                        app.rcs_system.changeCoordinate('Alpha_B1',app.rcs_system_crash.alpha_B1);
                        app.rcs_system.changeCoordinate('Alpha_B2',app.rcs_system_crash.alpha_B2);
                        app.rcs_system.changeCoordinate('Beta_B1',app.rcs_system_crash.beta_B1);
                        app.rcs_system.changeCoordinate('Beta_B2',app.rcs_system_crash.beta_B2);
                        
                        % Propiedades del sistema rcs:
                        propsRCSCrash = properties(app.rcs_system_crash);
    
                        % Excluimos las 3 últimas propiedades para que no
                        % sobreescriba la conexión con el PLC:
                        propsRCSCrash = propsRCSCrash(1:end-3);
    
                        % Sobreescribimos valores del crash:
                        for i = 1:length(propsRCSCrash)
                            app.rcs_system.(propsRCSCrash{i}) = app.rcs_system_crash.(propsRCSCrash{i});
                        end  
                    end
                end
                
                % Si no ha habido ningún problema habilitamos estos
                % botones:
                app.ResetConfigurationButton.Enable = "on";
                app.STARTMEASUREMENTButton.Enable = "on";
                app.ResetAppButton.Enable = "on"; 
            catch
                % En caso de que haya un crash cuando se está haciendo un
                % "returnOrigin()" o un "prepare()" de una medida en un
                % lanzamiento múltiple guardamos el fichero por el que se
                % ha quedado e indicamos que empiece por el punto inicial
                % del grid:
                if app.medidasMultiples % Solo si estamos con medidas múltiples
                    
                    % Para asegurar que no se sigan ejecutando las medidas
                    % múltiples:
                    app.stopExecution = true;
                    
                    % Hacemos que empiecen inicializados todos los
                    % parámetros: 
                    % Indicamos que empiece por el primer punto de cada
                    % sistema:
                    rd_points = 1; rcs_points = 1;
                    mes_count = 1; t = 0;
                    
                    % Guardamos los ficheros con los datos de las medidas 
                    % a partir del fichero que ha crasheado en las medidas 
                    % múltiples para cargar cargar luego todas esas medidas:
                    file_data = app.fileData(app.index_crash:end);
                    medidasMultiples = app.medidasMultiples;                    
                    crash_file = true;     

                    % Guardamos los parámetros que teníamos en el fichero 
                    % que ha tenido crash:
                    Pb = file_data{1}.Pb;
                    F0 = file_data{1}.F0;
                    FF = file_data{1}.FF;
                    N = file_data{1}.N;
                    IFBW = file_data{1}.IFBW;

                    % S-Parameters:
                    S_parameters = file_data{1}.S_parameters;
    
                    % Sweep type:
                    sweep_type = file_data{1}.sweep_type;
                    num_averages = file_data{1}.num_averages;
    
                    rot_desk_active = file_data{1}.rot_desk_active;
                    rot_desk = file_data{1}.rot_desk;
                    rcs_system_active = file_data{1}.rcs_system_active; % esto estaba comentado
                    rcs_system = file_data{1}.rcs_system;     

                    % Editamos la configuración de cada sistema para que luego
                    % en el siguiente "Prepare()" empiece por el primer punto
                    % del grid correspondiente:
                    
                    % Rot_desk:
                    if rot_desk_active
                        rot_desk.phi = rot_desk.phi_vec(1);
                        rot_desk.beta = rot_desk.beta_vec(1);
                        rot_desk.theta = rot_desk.theta_vec(1);
                        rot_desk.x = rot_desk.x_vec(1);
                        rot_desk.y = rot_desk.y_vec(1);
                        rot_desk.nphi = 1;
                        rot_desk.nbeta = 1;
                        rot_desk.ntheta = 1;
                        rot_desk.nx = 1;
                        rot_desk.ny = 1;
                        rot_desk.active_axis = 'X';
                    end

                    % RCS:
                    if rcs_system_active
                        rcs_system.phi = rcs_system.phi(1);
                        rcs_system.alpha_A1 = rcs_system.alpha_A1(1);
                        rcs_system.alpha_A2 = rcs_system.alpha_A2(1);
                        rcs_system.beta_A1 = rcs_system.beta_A1(1);
                        rcs_system.beta_A2 = rcs_system.beta_A2(1);
                        rcs_system.alpha_B1 = rcs_system.alpha_B1(1);
                        rcs_system.alpha_B2 = rcs_system.alpha_B2(1);
                        rcs_system.beta_B1 = rcs_system.beta_B1(1);
                        rcs_system.beta_B2 = rcs_system.beta_B2(1);
                        rcs_system.nphi = 1;
                        rcs_system.nalpha_A1 = 1;
                        rcs_system.nalpha_A2 = 1;
                        rcs_system.nbeta_A1 = 1;
                        rcs_system.nbeta_A2 = 1;
                        rcs_system.nalpha_B1 = 1;
                        rcs_system.nalpha_B2 = 1;
                        rcs_system.nbeta_B1 = 1;
                        rcs_system.nbeta_B2 = 1;
                        rcs_system.active_axis = 'Phi';
                    end
                    
                    % Inicializamos la matriz H para guardar el valor de los
                    % parámetros S en cada posición del grid:
                    H = struct();
                    for s_param = app.S_parameters
                        % We select the S-parameters actived:
                        if strlength(s_param) > 0
                             H.("S" + s_param) = zeros(app.vna.N, ...
                                app.rot_desk.Nphi,app.rot_desk.Nbeta,app.rot_desk.Ntheta,app.rot_desk.Nx,app.rot_desk.Ny, ...
                                app.rcs_system.Nphi, app.rcs_system.Ntheta, app.rcs_system.Nbeta, app.rcs_system.Nalpha); 
                        end                            
                    end                             

                    output = {'H','mes_count','t','Pb','F0','FF','N','IFBW', ...
                    'S_parameters', 'sweep_type', 'num_averages', 'crash_file', ...
                    'rot_desk_active','rot_desk','rd_points', ...
                    'rcs_system_activate','rcs_system','rcs_points','file_data', 'medidasMultiples'};

                    save("crash_states\" + string(datetime('now','Format','yyyy-MM-dd_HH_mm_ss')) + ".mat",output{:});
                    uialert(app.RCS_System_Controller,'<html><center>Measurement Failed!<br> Wait for Reset App</center></html>', ...
                    'Fatal Error','Icon','error', 'Interpreter','html');
                    
                    % Si estábamos lanzando varias medidas volvemos a
                    % habilitar el botón de "ResetApp" una vez acabe el
                    % bucle de medidas múltiples.
                else
                    % Si era una medida singular activamos el botón de
                    % "ResetApp":
                    app.ResetAppButton.Enable = "on";
                end                
            end                                          
        end

        % Button pushed function: ResetConfigurationButton
        function ResetConfigurationButtonPushed(app, event)
            app.ResetConfigurationButton.Enable = "off";
            app.STARTMEASUREMENTButton.Enable = "off";
            app.EMERGENCYSTOPButton.Enable = "on"; % Se repite abajo y lo 
            % pone a OFF
            
            % Si pulsamos el botón de ResetConfiguration volvemos al origen
            % que hemos establecido para el grid que estamos midiendo:
            app.rot_desk.returnOrigin();
            app.rcs_system.returnOrigin();

            app.MobileSystemsPanel.Enable = "on";
            app.VNAConfigurationPanel.Enable = "on";
            app.SaveConfigurationButton.Enable = "on";
            app.LoadConfigurationButton.Enable = "on";
            app.LoadStateButton.Enable = "on";
            app.ResetAppButton.Enable = "on";
            app.PrepareSingleButton.Enable = "on";
            app.EMERGENCYSTOPButton.Enable = "off";
        end

        % Button pushed function: STARTMEASUREMENTButton
        function STARTMEASUREMENTButtonPushed(app, event)
            app.STARTMEASUREMENTButton.Enable = "off";
            app.ResetConfigurationButton.Enable = "off";
            app.CloseAppButton.Enable = "off";
            app.ResetAppButton.Enable = "off";
             
            % Calculamos el grid en el que nos estamos moviendo:
            Nrd = app.rot_desk.Nphi*app.rot_desk.Nbeta*app.rot_desk.Ntheta*app.rot_desk.Nx*app.rot_desk.Ny;
            Nrcs = app.rcs_system.Nphi*app.rcs_system.Nalpha_A1*app.rcs_system.Nalpha_A2*app.rcs_system.Nbeta_A1*app.rcs_system.Nbeta_A2*...
                app.rcs_system.Nalpha_B1*app.rcs_system.Nalpha_B2*app.rcs_system.Nbeta_B1*app.rcs_system.Nbeta_B2*app.rcs_system.Nrho; 
            
            % Juntamos todos los sistemas:
            total = Nrd*Nrcs;
            
            dt_ms = 0;
            
            % Si hemos tenido un crasheo cargamos los valores de antes:
            if app.recover_crash
                % Cargamos el punto del grid por el que se había quedado
                % cada sistema y cargamos la matriz H con los parámetros
                % S21 guardados hasta el momento:
                rd0 = app.rd_points; rcs0 = app.rcs_points;
                H = app.H_crash;
                mes_count = app.count_crash - 1; t = app.t_crash;

                % aqui habria que guardar en un fichero no se que cosa

            else % Si no ha habido crasheo:
                % Indicamos que empiece por el primer punto de cada
                % sistema:
                rd0 = 1; rcs0 = 1;
                % Inicializamos la matriz H para guardar el valor de los
                % parámetros S en cada posición del grid:
                H = struct();
                for s_param = app.S_parameters
                    % We select the S-parameters actived:
                    if strlength(s_param) > 0
                         H.("S" + s_param) = zeros(app.vna.N, ...
                            app.rot_desk.Nphi,app.rot_desk.Nbeta,app.rot_desk.Ntheta,app.rot_desk.Nx,app.rot_desk.Ny, ...
                            app.rcs_system.Nphi, app.rcs_system.Nalpha_A1, app.rcs_system.Nalpha_A2, app.rcs_system.Nbeta_A1, app.rcs_system.Nbeta_A2, ...
                            app.rcs_system.Nalpha_B1, app.rcs_system.Nalpha_B2, app.rcs_system.Nbeta_B1, app.rcs_system.Nbeta_B2, app.rcs_system.Nrho);
                         % H.("S" + s_param) = 1;
                    end
                end

                mes_count = 0; t = 0;
            end
            
            % Creamos un vector de N puntos de frecuencia desde lower
            % frequency hasta upper frequency:
            Fv = linspace(app.vna.F0,app.vna.FF,app.vna.N)';
            dv = 1e-9*physconst('LightSpeed')*(0:app.vna.N-1)'/(app.vna.FF - app.vna.F0);

            xlim(app.UIAxes,[Fv(1),Fv(end)]);
            ylim(app.UIAxes,[-120,20]);
            xlim(app.UIAxes2,[dv(1),dv(end)]);

            ui_progress_dlg = uifigure("Name",'Progress Bar','Position',[706 578 425 148]);
            d = uiprogressdlg(ui_progress_dlg,'Title','Measuring...','ShowPercentage','on','Icon','info');
            
            time_unit = ["sec","min","h"];
            idx_el = 1; idx_tot = 1;

            try
                % En cada bucle recorremos hasta el máximo de puntos del
                % grid del sistema en cuestión:
                for rcs_points = rcs0:Nrcs 
                    for rd_points = rd0:Nrd
                        tic;
                        
                        % If we want to simulate an error: 
                        % if mes_count == 2 && app.index_crash == 2
                        %     error('ERROR');
                        % end                        

                        % Guardamos los parámetros S21 para
                        % cada punto del grid:
                        
                        % We loop through the loop according to the chosen
                        % S-parameters:                              
                        cla(app.UIAxes)    % Clear the axes
                        cla(app.UIAxes2)
                        hold(app.UIAxes, 'on')   % Permite superponer múltiples plots
                        hold(app.UIAxes2, 'on')  % Lo mismo para el segundo eje
                        traceNumber = 1; % Number of the trace to measure
                        app.vna.initSweep();

                        legend_labels = {};  % To save labels for the legend                         
                        for s_param = app.S_parameters
                            % We select the S-parameters actived:
                            if strlength(s_param) > 0
                                S_param_measure = app.vna.saveSParameter(traceNumber);
                                traceNumber = traceNumber + 1;
                                 H.("S" + s_param)(:, ...
                                    app.rot_desk.nphi,app.rot_desk.nbeta,app.rot_desk.ntheta,app.rot_desk.nx,app.rot_desk.ny, ...
                                    app.rcs_system.nphi, app.rcs_system.nalpha_A1, app.rcs_system.nalpha_A2, app.rcs_system.nbeta_A1, app.rcs_system.nbeta_A2, ...
                                    app.rcs_system.nalpha_B1, app.rcs_system.nalpha_B2, app.rcs_system.nbeta_B1, app.rcs_system.nbeta_B2, app.rcs_system.nrho) = S_param_measure; %S_param_measure; 
                                
                                % dict_H = containers.Maps({'phi'}, {});

                                % Plot the S-parameters actived:
                                HdB = mag2db(abs(S_param_measure));
                                PDP = mag2db(abs(ifft(S_param_measure)));
    
                                plot(app.UIAxes,Fv,HdB);
                                plot(app.UIAxes2,dv,PDP);

                                legend_labels{end+1} = sprintf('S' + s_param);  % Plot label
                            end
                        end
                        
                        legend(app.UIAxes, legend_labels, 'Location', 'best'); % Putting legend
                        legend(app.UIAxes2, legend_labels, 'Location', 'best'); % Putting legend
                        
                        hold(app.UIAxes, 'off')   % Opcional: vuelve a modo normal
                        hold(app.UIAxes2, 'off')

                        mes_count = mes_count + 1;
                        dt = toc + dt_ms; t = t + dt; Test = (total - mes_count)*dt + t; dt_ms = 0;

                        d.Value = mes_count/total;

                        if Test/3600 > 1
                            idx_tot = 3; Tshow = Test/3600;
                        elseif Test/60 > 1
                            idx_tot = 2; Tshow = Test/60;
                        else
                            idx_tot = 1; Tshow = Test;
                        end
                        
                        if t/3600 > 1
                            idx_el = 3; tshow = t/3600;
                        elseif t/60 > 1
                            idx_el = 2; tshow = t/60;
                        else
                            idx_el = 1; tshow = t;
                        end

                        d.Message = sprintf('Elapsed time: %.2f %s / %.2f %s...',tshow,time_unit(idx_el),Tshow,time_unit(idx_tot));

                        if rd_points < Nrd
                            tic; app.rot_desk.nextArrayPosition(); dt_ms = toc;
                        end
                    end

                    if rcs_points < Nrcs
                        tic; app.rcs_system.nextArrayPosition(); dt_ms = toc;
                    end
                end
                
                % Cogemos los elementos del array pero eliminando las
                % dimensiones de longitud 1 (eliminando los sistemas que no
                % han participado en la medición):                
                for s_param = app.S_parameters
                    % We select the S-parameters actived:
                    if strlength(s_param) > 0
                         H.("S" + s_param) = squeeze(H.("S" + s_param));                         
                    end                            
                end
                
                % Una vez que ha acabado la medida vuelve al origen 
                % establecido:                
                app.rot_desk.returnOrigin();                
                app.rcs_system.returnOrigin();
                
                % Volvemos a activar los botones:
                app.SaveConfigurationButton.Enable = "on";
                app.LoadConfigurationButton.Enable = "on";
                app.LoadStateButton.Enable = "on";
                app.ResetAppButton.Enable = "on";
                app.PrepareSingleButton.Enable = "on";
                app.CloseAppButton.Enable = "on";
                app.MobileSystemsPanel.Enable = "on";
                app.VNAConfigurationPanel.Enable = "on";
                app.EMERGENCYSTOPButton.Enable = "off";
                
                % Guardamos los parámetros S21 para cada punto del grid en
                % función del rango de frecuencia establecido:
                output = {'H','Fv'};
                
                % We save the movement range for each system:

                % Rotating Desk System: % HACER EN FORMATO EXCEL TAMBIÉN!!
                if app.rot_desk_active
                    if app.rot_desk.Nphi > 1
                        phi_rd = app.rot_desk.phi_vec;
                        output{end+1} = 'phi_rd';
                    end
                    if app.rot_desk.Nbeta > 1
                        beta_rd = app.rot_desk.beta_vec;
                        output{end+1} = 'beta_rd';
                    end
                    if app.rot_desk.Ntheta > 1
                        theta_rd = app.rot_desk.theta_vec;
                        output{end+1} = 'theta_rd';
                    end
                    if app.rot_desk.Nx > 1
                        x_rd = app.rot_desk.x_vec;
                        output{end+1} = 'x_rd';
                    end
                    if app.rot_desk.Ny > 1
                        y_rd = app.rot_desk.y_vec;
                        output{end+1} = 'y_rd';
                    end
                end                
                
                % RCS System: % ordenarlas segun el orden de la matriz H
                position = 1; % position in the matrix for the selected axis
                
                % They have to be sorted according to the order of the
                % matrix H:
                if app.rcs_system_active                    
                    if app.rcs_system.Nphi >= 1
                        phi_rcs = app.rcs_system.phi_vec;
                        % output{end+1} = 'phi_rcs';
                        % Phi vector:
                        dict_rcs = containers.Map({'phi'}, {phi_rcs});

                        % Position in the matrix:
                        dict_pos = containers.Map({'phi'}, {position});

                        % Increase the position:
                        position = position + 1;
                    end
                    if app.rcs_system.Nalpha_A1 >= 1
                        alpha_A1_rcs = app.rcs_system.alpha_A1_vec;                        
                        % output{end+1} = 'alpha_A1_rcs';

                        % Alpha_A1 vector:
                        dict_rcs = containers.Map({'alpha_A1'}, {alpha_A1_rcs});

                        % Position in the matrix:
                        dict_pos = containers.Map({'alpha_A1'}, {position});                         

                        % Increase the position:
                        position = position + 1;
                    end      
                    if app.rcs_system.Nalpha_A2 >= 1
                        % The zero is respect 180:
                        alpha_A2_rcs = 180 - app.rcs_system.alpha_A2_vec;                       
                        % output{end+1} = 'alpha_A2_rcs';

                        % Alpha_A2 vector:
                        dict_rcs = containers.Map({'alpha_A2'}, {alpha_A2_rcs});

                        % Position in the matrix:
                        dict_pos = containers.Map({'alpha_A2'}, {position});                         

                        % Increase the position:
                        position = position + 1;                       
                    end 
                    if app.rcs_system.Nbeta_A1 >= 1
                        beta_A1_rcs = app.rcs_system.beta_A1_vec;
                        % output{end+1} = 'beta_A1_rcs';

                        % Beta_A1 vector:
                        dict_rcs = containers.Map({'beta_A1'}, {beta_A1_rcs});

                        % Position in the matrix:
                        dict_pos = containers.Map({'beta_A1'}, {position});

                        % Increase the position:
                        position = position + 1; 
                    end                                                           
                    if app.rcs_system.Nbeta_A2 >= 1
                        beta_A2_rcs = app.rcs_system.beta_A2_vec;
                        % output{end+1} = 'beta_A2_rcs';

                        % Beta_A2 vector:
                        dict_rcs = containers.Map({'beta_A2'}, {beta_A2_rcs});

                        % Position in the matrix:
                        dict_pos = containers.Map({'beta_A2'}, {position});

                        % Increase the position:
                        position = position + 1;                         
                    end                    
                    if app.rcs_system.Nalpha_B1 >= 1
                        alpha_B1_rcs = app.rcs_system.alpha_B1_vec;
                        % output{end+1} = 'alpha_B1_rcs';
                        
                        % Alpha_B1 vector:
                        dict_rcs = containers.Map({'alpha_B1'}, {alpha_B1_rcs});

                        % Position in the matrix:
                        dict_pos = containers.Map({'alpha_B1'}, {position});                         

                        % Increase the position:
                        position = position + 1;                           
                    end                    
                    if app.rcs_system.Nalpha_B2 >= 1
                        alpha_B2_rcs = app.rcs_system.alpha_B2_vec;
                        % output{end+1} = 'alpha_B2_rcs';
                        
                        % Alpha_B2 vector:
                        dict_rcs = containers.Map({'alpha_B2'}, {alpha_B2_rcs});

                        % Position in the matrix:
                        dict_pos = containers.Map({'alpha_B2'}, {position});                         

                        % Increase the position:
                        position = position + 1;
                    end    
                    if app.rcs_system.Nbeta_B1 >= 1
                        beta_B1_rcs = app.rcs_system.beta_B1_vec;
                        % output{end+1} = 'beta_B1_rcs';
                        
                        % Beta_B1 vector:
                        dict_rcs = containers.Map({'beta_B1'}, {beta_B1_rcs});

                        % Position in the matrix:
                        dict_pos = containers.Map({'beta_B1'}, {position});

                        % Increase the position:
                        position = position + 1; 
                    end                                                        
                    if app.rcs_system.Nbeta_B2 >= 1
                        beta_B2_rcs = app.rcs_system.beta_B2_vec;
                        % output{end+1} = 'beta_B2_rcs';

                        % Beta_B2 vector:
                        dict_rcs = containers.Map({'beta_B2'}, {beta_B2_rcs});

                        % Position in the matrix:
                        dict_pos = containers.Map({'beta_B2'}, {position});

                        % Increase the position:
                        position = position + 1; 
                    end                    
                    if app.rcs_system.Nrho >= 1
                        rho_rcs = app.rcs_system.rho_vec;
                        % output{end+1} = 'rho_rcs';

                        % Rho vector:
                        dict_rcs = containers.Map({'rho'}, {rho_rcs});

                        % Position in the matrix:
                        dict_pos = containers.Map({'rho'}, {position});
                    end   

                    % Save the dictionaries:
                    output{end+1} = 'dict_rcs';
                    output{end+1} = 'dict_pos';
                end

                %%%%%%%%%%%%%%%%%%%%
                close(ui_progress_dlg);
                
                % Si estamos ejecutando una Medida Singular le damos al
                % usuario la opción de guardar el archivo de forma manual:
                if ~app.medidasMultiples
                    uialert(app.RCS_System_Controller,'Measurement Finished!', ...
                        'Success','Icon','success');
                    uisave(output,sprintf('measurements\\measurement_%dGHz_%dGHz_%d_%dHz.mat',app.F0,app.FF,app.N,app.IFBW));
                else % Si tenemos medidas múltiples se guarda automáticamente el archivo
                    save(sprintf('measurements\\measurement_%dGHz_%dGHz_%d_%dHz.mat', app.F0, app.FF, app.N, app.IFBW), output{:});
                end
            catch % Si ha habido un crash guardamos los valores que teníamos en el fichero de crash
                close(ui_progress_dlg);

                Pb = app.PowerdBmEditField.Value;
                F0 = app.LowerFreqGHzEditField.Value;
                FF = app.UpperFreqGHzEditField.Value;
                N = app.NumberofpointsEditField.Value;
                IFBW = app.IFBWHzEditField.Value;

                % S-Parameters:
                S_parameters = app.S_parameters;
    
                % Sweep type:
                sweep_type = app.sweep_type;
                num_averages = app.num_averages;

                rot_desk_active = app.rot_desk_active;
                rot_desk = app.rot_desk;
                rcs_system_active = app.rcs_system_active;
                rcs_system = app.rcs_system;                    

                % Ponemos que el fichero tiene un crash:
                crash_file = true;                                         
                
                % Guardamos los ficheros con los datos de las medidas 
                % a partir del fichero que ha crasheado en las medidas 
                % múltiples para cargar cargar luego todas esas medidas:
                file_data = app.fileData(app.index_crash:end);
                medidasMultiples = app.medidasMultiples;
                
                if(app.medidasMultiples)                                             
                    % Indicamos que no siga ejecutando las siguientes 
                    % medidas múltiples y hasta que no se salga del bucle 
                    % de medidas múltiples que no deje pulsar el botón de
                    % "ResetApp" para no sobreescribir nuevos valores:
                    app.stopExecution = true;
                else
                    % Si no se estaban ejecutando medidas múltiples si 
                    % no una medida singular dejamos pulsar el botón de
                    % "ResetApp" de forma instantánea:
                    app.ResetAppButton.Enable = "on";
                end   

                % We close the connection with the PLC:
                try app.rot_desk.closeConnection(); catch, end

                output = {'H','mes_count','t','Pb','F0','FF','N','IFBW','crash_file', ...
                    'S_parameters', 'sweep_type', 'num_averages', ...
                    'rot_desk_active','rot_desk','rd_points', ...
                    'rcs_system_active','rcs_system','rcs_points','file_data', 'medidasMultiples'};

                save("crash_states\" + string(datetime('now','Format','yyyy-MM-dd_HH_mm_ss')) + ".mat",output{:});
                uialert(app.RCS_System_Controller,'<html><center>Measurement Failed!<br> Wait for Reset App</center></html>', ...
                    'Fatal Error','Icon','error', 'Interpreter','html');                
            end
        end

        % Button pushed function: LaunchMultipleButton
        function LaunchMultipleButtonPushed(app, event)
           
            % Comprobamos si se ha conectado correctamente el puerto del
            % PLC o no:        

            systems = {													
                "Rot Desk System", app.rot_desk_save;
                "RCS System", app.rcs_system_save;
            };
            
            for i = 1:size(systems, 1)
                if ~systems{i, 2}
                    uialert(app.RCS_System_Controller, ...
                        sprintf('Check the PLC connection for the %s', systems{i, 1}), ...
                        'Check the PLC connection');
                    return;
                end
            end                         
            
            % Desactivamos el botón de "Launch Multiple" una vez le hemos
            % dado (para que no se le de otra vez):
            app.LaunchMultipleButton.Enable = 'off';
                        
            % En el caso de tener varios ficheros llamamos a la función
            % "PrepareSingleButtonPushed" y "STARTMEASUREMENTButtonPushed"
            % para preparar e iniciar la medida de cada fichero de forma 
            % automática. Como los parámetros del primer fichero ya se han
            % cargado de forma automática empezamos ejecutando el primer
            % fichero:
            for i = 1:length(app.fileData)
                % Salimos del bucle cuando se ha pulasdo "EmergencyStop()"
                % o cuando ha habido un error y no queremos seguir
                % ejecutando las siguientes medidas múltiples:
                if app.stopExecution
                    break;
                end
            
                % Guardamos el índice del fichero que estamos ejecutando
                % por si hay un crash y hay que recuperarlo:
                app.index_crash = i;

                % Abrimos la interfaz de configuración del sistema
                % correspondiente y le damos a "Save Configuration" de
                % forma automática para que el botón se ponga en VERDE y se
                % guarde la configuración antes de lanzar la medida:
                if (i~=1) 

                    % Si es la primera medida no ejecutamos automáticamente
                    % para que el usuario se asegure al abrir la
                    % configuración de que se ha conectado correctamente al
                    % puerto del arduino:
                    
                    % Check Rotation Desk:
                    if app.rot_desk_active
                        % Pulsamos el botón de "Configuration"
                        configurationAntenna_MeasurementsButtonPushed(app, event)
                        % Pulsamos el botón de "Save Configuration"
                        app.ConfigurationRotDesk.saveConfiguration();
                    end
                    
                    % Check RCS System:
                    if app.rcs_system_active
                        configurationRCSButtonPushed(app, event)
                        app.Configuration_RCS_system.saveConfiguration();
                    end
                    
                end

                % Para dejar tiempo entre que se guarda la configuración y
                % se ejecuta el "PREPARE()" y el "START MEASUREMENT":
                pause(2);

                % Hacemos el prepare Measurement:
                PrepareSingleButtonPushed(app);
                
                % Nos aseguramos de que no haya habido un fallo antes de
                % lanzar la siguiente medida:
                if app.stopExecution
                    break;
                end

                % Lanzamos la medida del fichero correspondiente:
                STARTMEASUREMENTButtonPushed(app); 
                
                % Desactivamos los botones para que no se puedan usar
                % mientras se realizan medidas:
                app.LaunchMultipleButton.Enable = 'off';
                app.PrepareSingleButton.Enable = 'off';

                % Cargamos los datos del siguiente fichero y volvemos a
                % repetirlo:
                fileIndex = i + 1;
                if fileIndex <= length(app.fileData)
                    loadDataFile(app, fileIndex)
                end 

                % Dejamos un tiempo entre medidas para asegurar:
                %pause(delay_total); % Suma de los delays de cada eje del sistema actual
            end      
            
            % Indicamos que ya se han realizado todas las medidas:
            if i == length(app.fileData) && ~app.stopExecution
                uialert(app.RCS_System_Controller,'Measurement Finished!', ...
                'Success','Icon','success');
            end

            % Para el siguiente lanzamiento volvemos a hacer que se
            % compruebe la conectividad con el COM del Arduino:
            app.STATUSEditField.BackgroundColor = app.colorRojo;
            app.STATUSEditField.FontColor = app.colorBlanco;
            app.STATUSEditField.Value = "CHECK COM PORT";

            % Volvemos a activar el botón de Reset App:
            app.ResetAppButton.Enable = "on";            
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            % Optimizar con una bucle for y una lista de nombres???
            % app.rot_desk_A.emergencyStop();
            % app.rot_desk_B.emergencyStop();
            % app.planar_system.emergencyStop();
            % app.angular_system.emergencyStop();
            % app.solar_system.emergencyStop();
            % app.stirrer.emergencyStop();
            % 
            % % Cerramos la conexión con el Arduino:
            try app.rot_desk.closeConnection(); catch, end
            % try app.rot_desk_B.closeConnection(); catch, end
            % try app.planar_system.closeConnection(); catch, end
            % try app.angular_system.closeConnection(); catch, end
            % try app.solar_system.closeConnection(); catch, end
            % try app.stirrer.closeConnection(); catch, end            
            % 
            % % Indicamos que ha habido un error:
            app.rcs_system.flag_stopExecution = true;            
                                
            uialert(app.RCS_System_Controller, ...
            sprintf(['<html><center><b>Emergency Stop Pushed!' ...
            '<br>Wait for Reset App</b></center></html>']), ...
            'Fatal Error', 'Icon', 'error', 'Interpreter', 'html');
            error('EMERGENCY STOP PUSHED!');                                  
        end

        % Button pushed function: ResetAppButton
        function ResetAppButtonPushed(app, event)
            app.PrepareSingleButton.Enable = "off";
            app.STARTMEASUREMENTButton.Enable = "off";
            app.LaunchMultipleButton.Enable = "off";
            app.ResetConfigurationButton.Enable = 'off';
            app.ApplyConfigurationButton.Enable = "off";
            app.EMERGENCYSTOPButton.Enable = "off";
            app.MobileSystemsPanel.Enable = "on";
            app.VNAConfigurationPanel.Enable = "on";
            app.CloseAppButton.Enable = 'on';

            % Añadimos que se vuelvan a poner activos los botones
            % inferiores por si ha habido un crasheo:
            app.LoadConfigurationButton.Enable = 'on';
            app.LoadStateButton.Enable = 'on';
            app.SaveConfigurationButton.Enable = 'on';
           
            app.PowerdBmEditField.Value = 0;
            app.UpperFreqGHzEditField.Value = 20;
            app.LowerFreqGHzEditField.Value = 10;
            app.NumberofpointsEditField.Value = 101;
            app.IFBWHzEditField.Value = 1000;
            
            % Reset sweep type to singular:
            app.Singular_Sweep.Value = 1;
            app.Average_Sweep.Value = 0;
            app.Num_of_averages_input.Enable = "off";
            app.Num_of_averages_input.Value = 1;            

            app.STATUSEditField.BackgroundColor = app.colorBlanco;
            app.STATUSEditField.Value = "";

            % Remove plot legends:
            legend(app.UIAxes, 'off');
            legend(app.UIAxes2, 'off');

            try delete(app.UIAxes.Children); catch, end
            try delete(app.UIAxes2.Children); catch, end

            % Antena Measurements:
            app.rot_desk_active = false;
            app.rot_desk_save = true;            
            app.rot_desk = DummyClass();
            app.AntennaMeasurementsCheckBox.Value = false;
            app.AntennaMeasurementsCheckBox.Enable = "on";
            AntennaMeasurementsCheckBoxValueChanged(app);

            % RCS Measurements:
            app.RCSMeasurementsCheckBox.Value = false;
            app.rcs_system_save= true;            
            app.rcs_system = DummyClass();
            app.RCSMeasurementsCheckBox.Value = false;
            app.RCSMeasurementsCheckBox.Enable = "on";
            RCSMeasurementsCheckBoxValueChanged(app);

            % Try to close the connection with the PLC if it is active:
            try app.rot_desk.closeConnection(); catch, end
            try app.rcs_system.closeConnection(); catch, end                        
            
            app.recover_crash = false;

            % Volvemos a resetar estos valores:
            app.medidasMultiples = false;            
            app.stopExecution = false;
            app.index_crash = 1;
            
            % Reset the S-Parameters:
            app.Input_S_parameter.Value = '';
            app.DropDown_S_Parameters.Items = {'S21'};
            app.DropDown_S_Parameters.Value = 'S21';
        end

        % Button pushed function: CloseAppButton
        function CloseAppButtonPushed(app, event)
            % Close the APP whe you click on the "Close App" button
            RCS_System_ControllerCloseRequest(app);
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            web('https://encrypted-tbn0.gstatic.com/images?q=tbn:ANd9GcTH92gqrOwtHMvSwuOpiv-ukkuwEBCAV-oTFw&s', '-browser');        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RCS_System_Controller = uifigure('Visible', 'off');
            app.RCS_System_Controller.Name = 'RCS System Controller';
            app.RCS_System_Controller.Icon = fullfile(pathToMLAPP, 'icons', 'swt_lab_icon.png');
            app.RCS_System_Controller.CloseRequestFcn = createCallbackFcn(app, @RCS_System_ControllerCloseRequest, true);
            app.GridLayout = uigridlayout(app.RCS_System_Controller);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Last Measurement - Power Delay Profile')
            xlabel(app.UIAxes2, 'Distance (m)')
            ylabel(app.UIAxes2, 'Magnitude (dB)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Last Measurement - Frequency Response')
            xlabel(app.UIAxes, 'Frequency (GHz)')
            ylabel(app.UIAxes, 'Magnitude (dB)')
            zlabel(app.UIAxes, 'Z')
            app.MobileSystemsPanel = uipanel(app.GridLayout);
            app.MobileSystemsPanel.Title = 'Mobile Systems';
            app.GridLayout2 = uigridlayout(app.MobileSystemsPanel);
            app.AntennaMeasurementsCheckBox = uicheckbox(app.GridLayout2);
            app.AntennaMeasurementsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AntennaMeasurementsCheckBoxValueChanged, true);
            app.AntennaMeasurementsCheckBox.Text = 'Antenna Measurements';
            app.configurationAntenna_Measurements = uibutton(app.GridLayout2, 'push');
            app.configurationAntenna_Measurements.ButtonPushedFcn = createCallbackFcn(app, @configurationAntenna_MeasurementsButtonPushed, true);
            app.configurationAntenna_Measurements.Enable = 'off';
            app.configurationAntenna_Measurements.Text = 'Configuration';
            app.configurationRCS = uibutton(app.GridLayout2, 'push');
            app.configurationRCS.ButtonPushedFcn = createCallbackFcn(app, @configurationRCSButtonPushed, true);
            app.configurationRCS.Enable = 'off';
            app.configurationRCS.Text = 'Configuration';
            app.RCSMeasurementsCheckBox = uicheckbox(app.GridLayout2);
            app.RCSMeasurementsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCSMeasurementsCheckBoxValueChanged, true);
            app.RCSMeasurementsCheckBox.Text = 'RCS Measurements';
            app.MakesureyouareconnectedtothePLCbeforeloadingmeasurementsLabel = uilabel(app.GridLayout2);
            app.MakesureyouareconnectedtothePLCbeforeloadingmeasurementsLabel.Text = {'⚠️ Make sure you are connected to the PLC before '; 'loading measurements. '};
            app.VNAConfigurationPanel = uipanel(app.GridLayout);
            app.VNAConfigurationPanel.Title = 'VNA Configuration';
            app.GridLayout3 = uigridlayout(app.VNAConfigurationPanel);
            app.CheckConnectivityButton = uibutton(app.GridLayout3, 'push');
            app.CheckConnectivityButton.ButtonPushedFcn = createCallbackFcn(app, @CheckConnectivityButtonPushed, true);
            app.CheckConnectivityButton.Text = 'Check Connectivity';
            app.STATUSEditField = uieditfield(app.GridLayout3, 'text');
            app.STATUSEditField.Editable = 'off';
            app.ApplyConfigurationButton = uibutton(app.GridLayout3, 'push');
            app.ApplyConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyConfigurationButtonPushed, true);
            app.ApplyConfigurationButton.Enable = 'off';
            app.ApplyConfigurationButton.Text = 'Apply Configuration';
            app.General_Parameters_VNA = uipanel(app.GridLayout3);
            app.General_Parameters_VNA.Title = 'General parameters';
            app.GridLayout4 = uigridlayout(app.General_Parameters_VNA);
            app.VNAModelDropDownLabel = uilabel(app.GridLayout4);
            app.VNAModelDropDownLabel.Text = 'VNA Model';
            app.VNAModelDropDown = uidropdown(app.GridLayout4);
            app.VNAModelDropDown.Items = {'R&S ZNA67', 'R&S ZVA24', 'Virtual VNA'};
            app.VNAModelDropDown.Value = 'R&S ZNA67';
            app.NumberofpointsEditFieldLabel = uilabel(app.GridLayout4);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.NumberofpointsEditField.Limits = [1 Inf];
            app.NumberofpointsEditField.RoundFractionalValues = 'on';
            app.NumberofpointsEditField.ValueDisplayFormat = '%d';
            app.NumberofpointsEditField.Value = 101;
            app.IFBWHzEditFieldLabel = uilabel(app.GridLayout4);
            app.IFBWHzEditFieldLabel.Text = 'IFBW (Hz)';
            app.IFBWHzEditField = uieditfield(app.GridLayout4, 'numeric');
            app.IFBWHzEditField.Limits = [1 Inf];
            app.IFBWHzEditField.RoundFractionalValues = 'on';
            app.IFBWHzEditField.ValueDisplayFormat = '%d';
            app.IFBWHzEditField.Value = 1000;
            app.PowerdBmEditFieldLabel = uilabel(app.GridLayout4);
            app.PowerdBmEditFieldLabel.Text = 'Power (dBm)';
            app.PowerdBmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.UpperFreqGHzEditFieldLabel = uilabel(app.GridLayout4);
            app.UpperFreqGHzEditFieldLabel.Text = 'Upper Freq. (GHz)';
            app.UpperFreqGHzEditField = uieditfield(app.GridLayout4, 'numeric');
            app.UpperFreqGHzEditField.Limits = [0.01 Inf];
            app.UpperFreqGHzEditField.ValueChangedFcn = createCallbackFcn(app, @UpperFreqGHzEditFieldValueChanged, true);
            app.UpperFreqGHzEditField.Value = 20;
            app.LowerFreqGHzEditFieldLabel = uilabel(app.GridLayout4);
            app.LowerFreqGHzEditFieldLabel.Text = 'Lower Freq. (GHz)';
            app.LowerFreqGHzEditField = uieditfield(app.GridLayout4, 'numeric');
            app.LowerFreqGHzEditField.Limits = [0.01 Inf];
            app.LowerFreqGHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowerFreqGHzEditFieldValueChanged, true);
            app.LowerFreqGHzEditField.Value = 10;
            app.SParameterstomeasurePanel = uipanel(app.GridLayout3);
            app.SParameterstomeasurePanel.Title = 'S-Parameters to measure';
            app.GridLayout5 = uigridlayout(app.SParameterstomeasurePanel);
            app.DropDown_S_Parameters = uidropdown(app.GridLayout5);
            app.DropDown_S_Parameters.Items = {'S21'};
            app.DropDown_S_Parameters.Value = 'S21';
            app.Input_S_parameter = uieditfield(app.GridLayout5, 'text');
            app.Add_S_parameter = uibutton(app.GridLayout5, 'push');
            app.Add_S_parameter.ButtonPushedFcn = createCallbackFcn(app, @Add_S_parameterButtonPushed, true);
            app.Add_S_parameter.Text = 'Add';
            app.Delete_S_parameter = uibutton(app.GridLayout5, 'push');
            app.Delete_S_parameter.ButtonPushedFcn = createCallbackFcn(app, @Delete_S_parameterButtonPushed, true);
            app.Delete_S_parameter.Text = 'Delete';
            app.SweeptypePanel = uipanel(app.GridLayout3);
            app.SweeptypePanel.Title = 'Sweep type';
            app.GridLayout6 = uigridlayout(app.SweeptypePanel);
            app.Singular_Sweep = uicheckbox(app.GridLayout6);
            app.Singular_Sweep.ValueChangedFcn = createCallbackFcn(app, @Singular_SweepValueChanged, true);
            app.Singular_Sweep.Text = 'Singular';
            app.Singular_Sweep.Value = true;
            app.Average_Sweep = uicheckbox(app.GridLayout6);
            app.Average_Sweep.ValueChangedFcn = createCallbackFcn(app, @Average_SweepValueChanged, true);
            app.Average_Sweep.Text = 'Average';
            app.NumofaveragesLabel = uilabel(app.GridLayout6);
            app.NumofaveragesLabel.Text = 'Num of averages';
            app.Num_of_averages_input = uieditfield(app.GridLayout6, 'numeric');
            app.Num_of_averages_input.Enable = 'off';
            app.Num_of_averages_input.Value = 1;
            app.EMERGENCYSTOPButton = uibutton(app.GridLayout, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Enable = 'off';
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.ResetConfigurationButton = uibutton(app.GridLayout, 'push');
            app.ResetConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetConfigurationButtonPushed, true);
            app.ResetConfigurationButton.Enable = 'off';
            app.ResetConfigurationButton.Text = 'Reset Configuration';
            app.PrepareSingleButton = uibutton(app.GridLayout, 'push');
            app.PrepareSingleButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareSingleButtonPushed, true);
            app.PrepareSingleButton.Enable = 'off';
            app.PrepareSingleButton.Text = 'Prepare Single';
            app.STARTMEASUREMENTButton = uibutton(app.GridLayout, 'push');
            app.STARTMEASUREMENTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTMEASUREMENTButtonPushed, true);
            app.STARTMEASUREMENTButton.Enable = 'off';
            app.STARTMEASUREMENTButton.Text = 'START MEASUREMENT';
            app.SaveConfigurationButton = uibutton(app.GridLayout, 'push');
            app.SaveConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigurationButtonPushed, true);
            app.SaveConfigurationButton.Text = 'Save Configuration';
            app.LoadConfigurationButton = uibutton(app.GridLayout, 'push');
            app.LoadConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigurationButtonPushed, true);
            app.LoadConfigurationButton.Text = 'Load Configuration';
            app.CloseAppButton = uibutton(app.GridLayout, 'push');
            app.CloseAppButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAppButtonPushed, true);
            app.CloseAppButton.Text = 'Close App';
            app.LoadStateButton = uibutton(app.GridLayout, 'push');
            app.LoadStateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStateButtonPushed, true);
            app.LoadStateButton.Text = 'Load State';
            app.ResetAppButton = uibutton(app.GridLayout, 'push');
            app.ResetAppButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAppButtonPushed, true);
            app.ResetAppButton.Text = 'Reset App';
            app.Image = uiimage(app.GridLayout);
            app.Image.URL = 'https://www.ugr.es/';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'ugr_bk.png');
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.URL = 'https://swat.ugr.es/';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'icons', 'swt_lab_bk.png');
            app.LaunchMultipleButton = uibutton(app.GridLayout, 'push');
            app.LaunchMultipleButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchMultipleButtonPushed, true);
            app.LaunchMultipleButton.Enable = 'off';
            app.LaunchMultipleButton.Text = 'Launch Multiple';
            app.Exportar_datos = uibutton(app.GridLayout, 'push');
            app.Exportar_datos.ButtonPushedFcn = createCallbackFcn(app, @Exportar_datosButtonPushed, true);
            app.Exportar_datos.Text = 'Export data';
            app.Post_processing = uibutton(app.GridLayout, 'push');
            app.Post_processing.ButtonPushedFcn = createCallbackFcn(app, @Post_processingButtonPushed, true);
            app.Post_processing.Text = 'POST PROCESSING';
            app.VNAConfigurationButton = uibutton(app.RCS_System_Controller, 'push');
            app.VNAConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @VNAConfigurationButtonPushed, true);
            app.VNAConfigurationButton.Enable = 'off';
            app.VNAConfigurationButton.Visible = 'off';
            app.VNAConfigurationButton.Text = {'Ports and Parameters'; 'configuration'};
            % Show the figure after all components are created
            app.RCS_System_Controller.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_master_RCS

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RCS_System_Controller)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.RCS_System_Controller)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RCS_System_Controller)
        end
    end
end